classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GenerarglbuloButton      matlab.ui.control.Button
        sl_psize_n               matlab.ui.control.Slider
        TamaoplacanegativaLabel  matlab.ui.control.Label
        DeteccindeMalariaLabel   matlab.ui.control.Label
        sl_psize                 matlab.ui.control.Slider
        TamaoplacapositivaLabel  matlab.ui.control.Label
        sl_distance              matlab.ui.control.Slider
        DistanciaentreplacasSliderLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
        axes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function update_plot(app)
            
            %Entregable 1 en 3D 4 cargas

            lzp = app.sl_psize.Value;   %distancia del centro a la orilla por eje z (placa positiva)
            lzn = app.sl_psize_n.Value; %distancia del centro a la orilla por eje z (placa negativa)
            lx = 0.005;  %ancho de la placa
            ly = 0.1;  %distancia del centro a la orilla por eje y
            movX = app.sl_distance.Value./2; % mitad de la distancia entre placas
            
            %VERTICES Y CARAS DE LÁMINA (+)
                       % x       y     z
            vertices = [-lx-movX -ly  -lzp; %v1
                        -lx-movX   ly  -lzp; %v2
                         -movX   ly  -lzp; %v3
                         -movX  -ly  -lzp; %v4
                        -lx-movX  -ly   lzp; %v5
                        -lx-movX   ly   lzp; %v6
                         -movX   ly   lzp; %v7
                         -movX  -ly   lzp]; %v8
                     
            caras = [1 5 8 4; %c1
                     1 5 6 2; %c2
                     2 3 7 6; %c3
                     3 4 8 7; %c4
                     5 6 7 8; %c5
                     1 2 3 4]; %c6
            
                 
            %VERTICES Y CARAS DE LÁMINA (-)
                       % x       y     z
            vertices2 = [+movX -ly  -lzn; %v1
                        +movX   ly  -lzn; %v2
                         lx+movX   ly  -lzn; %v3
                         lx+movX  -ly  -lzn; %v4
                        +movX  -ly   lzn; %v5
                        +movX   ly   lzn; %v6
                         lx+movX   ly   lzn; %v7
                         lx+movX  -ly   lzn]; %v8
            
            maxlz = max([lzp, lzn]);
            xl = linspace(-2*movX,2*movX,11);  % Valores de x que vamos a evaluar
            yl = linspace(-1.5*ly,1.5*ly,11);  % Valores de y que vamos a evaluar
            zl = linspace(-1.5*maxlz,1.5*maxlz,11); % Valores de z que vamos a evaluar
            
            [xGrid, yGrid, zGrid]= meshgrid(xl,yl,zl);
            
            %PROPIEDADES DEL CAMPO ELÉCTRICO
            Q = 1e-6; %Carga (+)            
            eps0 = 8.854e-12; %Constante dieléctrica
            
            % Placa positiva
            ypl = linspace(-ly, ly, 10);
            zpl = linspace(-lzp, lzp, 10);
            [yp, zp] = meshgrid(ypl, zpl);
            xp = -movX*ones(size(yp));
            qp = Q./numel(xp);
            
            Ex = 0;
            Ey = 0;
            Ez = 0;
            for k1 = 1:size(xp, 1)
                for k2 = 1:size(xp, 2)
                    rx = xGrid - xp(k1, k2);
                    ry = yGrid - yp(k1, k2);
                    rz = zGrid - zp(k1, k2);
                    
                    r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
                    Ex = Ex + ((qp / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
                    Ey = Ey + ((qp / 4*pi*eps0) * ry) ./ r.^3;
                    Ez = Ez + ((qp / 4*pi*eps0) * rz) ./ r.^3;
                end
            end
            
            % Placa negativa
            ynl = linspace(-ly, ly, 10);
            znl = linspace(-lzn, lzn, 10);
            [yn, zn] = meshgrid(ynl, znl);
            xn = movX*ones(size(yn));
            qn = -Q./numel(xn);

            for k1 = 1:size(xn, 1)
                for k2 = 1:size(xn, 2)
                    rx = xGrid - xn(k1, k2);
                    ry = yGrid - yn(k1, k2);
                    rz = zGrid - zn(k1, k2);
                    
                    r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
                    Ex = Ex + ((qn / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
                    Ey = Ey + ((qn / 4*pi*eps0) * ry) ./ r.^3;
                    Ez = Ez + ((qn / 4*pi*eps0) * rz) ./ r.^3;
                end
            end
            
            
            %A = 4*(ly * lz); %Área de la lámina positiva


%             %PROPIEDADES PARA LOS VECTORES
%             Px = xGrid;
%             Py = yGrid;
%             Pz = zGrid;
%             
%             % Campo Q1 (Placa positiva) - TOP -
%             rx = xGrid -(-movX);
%             ry = yGrid - 0;
%             rz = zGrid - lzp;
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex1 = ((q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey1 = ((q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez1 = ((q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q2 (Placa positiva) - BOT -
%             rx = xGrid -(movX);
%             ry = yGrid - 0;
%             rz = zGrid - (-lzp);
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex2 = ((q / 4*pi*eps0) * rx) ./ r.^3;
%             Ey2 = ((q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez2 = ((q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q3 (Placa positiva) - MID -
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = 0;
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex3 = ((q / 4*pi*eps0) * rx) ./ r.^3;
%             Ey3 = ((q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez3 = ((q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q4 (Placa positiva) - TOP MID -
%             
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = zGrid - 0.25.*lzp;
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex4 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey4 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez4 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q5 (Placa positiva) - BOT MID -
%             
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = zGrid - 0.25.*(-lzp);
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex5 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey5 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez5 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             
%             % --- %
%             
%             
%             % Campo Q6 (Placa negativa) - TOP -
%             rx = xGrid -(movX);
%             ry = yGrid - 0;
%             rz = zGrid - lzn;
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex6 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey6 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez6 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q7 (Placa negativa) - BOT -
%             rx = xGrid -(movX);
%             ry = yGrid - 0;
%             rz = zGrid - (-lzn);
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex7 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey7 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez7 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q8 (Placa negativa) - MID -
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = 0;
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex8 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey8 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez8 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q9 (Placa negativa) - TOP MID -
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = zGrid - 0.25.*(lzn);
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex9 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey9 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez9 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             % Campo Q10 (Placa negativa) - BOT MID - 
%             rx = xGrid - (movX);
%             ry = yGrid - 0;
%             rz = zGrid - 0.25.*(-lzn);
%             r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
%             Ex10 = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
%             Ey10 = ((-q / 4*pi*eps0) * ry) ./ r.^3;
%             Ez10 = ((-q / 4*pi*eps0) * rz) ./ r.^3;
%             
%             
%             %COMPONENTES DE LOS VECTORES
%             Ex = Ex1 + Ex2 +Ex3 + Ex4 + Ex5 + Ex6 + Ex7 + Ex8 + Ex9 + Ex10;
%             Ey = Ey1 + Ey2 +Ey3 + Ey4 + Ey5 + Ey6 + Ey7 + Ey8 + Ey9 + Ey10;
%             Ez = Ez1 + Ez2 +Ez3 + Ez4 + Ez5 + Ez6 + Ez7 + Ez8 + Ez9 + Ez10;
            
            E = sqrt(Ex.^2 + Ey .^2 + Ez.^2)
            
            cla(app.axes, "reset")
            
            hold(app.axes, "on")
            
            patch(app.axes,'Faces',caras,'Vertices',vertices,'FaceColor','r','facealpha',0.5);
            patch(app.axes,'Faces',caras,'Vertices',vertices2,'FaceColor','b','facealpha',0.5);
            
            scatter3(app.axes, reshape(xp, 1, numel(xp)), reshape(yp, 1, numel(yp)), reshape(zp, 1, numel(zp)),'g','linewidth',5);
            
            scatter3(app.axes, reshape(xn, 1, numel(xn)), reshape(yn, 1, numel(yn)), reshape(zn, 1, numel(zn)),'g','linewidth',5);
            
            axis(app.axes, "equal")
            xlabel(app.axes,'x')
            ylabel(app.axes,'y')
            zlabel(app.axes,'z')
            
            quiver3(app.axes,xGrid, yGrid, zGrid,Ex./E,Ey./E,Ez./E,"Color", [0.65,0.97,1.00])
            
            hold(app.axes, "off")


            axis(app.axes, [-2*movX 2*movX -1.5*ly 1.5*ly -1.5*maxlz 1.5*maxlz]);
            view(app.axes, 30,30); %Establecer la vista de el espacio 3D
            grid(app.axes, "on");

            
        end
    end
    
    methods (Access = private)
        
        function globulos(app)
            
            lzp = app.sl_psize.Value;   %distancia del centro a la orilla por eje z (placa positiva)
            lzn = app.sl_psize_n.Value; %distancia del centro a la orilla por eje z (placa negativa)
            movX = app.sl_distance.Value./2; % mitad de la distancia entre placas
            lz = max([lzp, lzn]);
                        
            %ancho placa 0.005
            %-0.03 a 0.05
            PosX = 0.25*((rand() * movX * 2) - movX); %ENTERO 1
            
            d = rand() * .016 - 0.008;
            
            PosY = 0.5*((lz * 2) * rand() - lz); %ENTERO 1
            
            PosZ = 0;
            
            
            hold(app.axes, 'on')
            
            %CREAR ESFERA
            RadioEsfera = 0.008;
            [x,y,z] = sphere(10);
            x = x * RadioEsfera;
            y = y * RadioEsfera;
            z = z * RadioEsfera;
            surf(app.axes,x + PosX,y + PosY,z + PosZ,'FaceColor','#ffa200','EdgeColor','#ffa200','Facealpha',0.3);
            
            RadioCargasG = 0.002;
                       
            [x,y,z] = sphere(10);
            x = x * RadioCargasG;
            y = y * RadioCargasG;
            z = z * RadioCargasG;
            
            %CREAR CARGA E1 (-)
            surf(app.axes, x + PosX + d,y + PosY,z + PosZ,'FaceColor','b','EdgeColor','b');
            
            %CREAR CARGA E2 (+)
            surf(app.axes,x + PosX - d,y + PosY,z + PosZ,'FaceColor','r','EdgeColor','r');
            
            hold(app.axes, 'off')
            
            %% Calculo de campo
            
            q = 1e-6;            
            eps0 = 8.854e-12; %Constante dieléctrica
            
            rx = PosX;
            ry = PosY;
            rz = PosZ;
            r = sqrt(rx.^2 + ry.^2 + rz.^2); % distancia de la particula (magnitud)
            ExD = ((-q / 4*pi*eps0) * rx) ./ r.^3; % E = [(k*q) / sqrt ( (x-qx)^2 + (y-qy)^2 + (z-qz)^2) )^2 ] * r unitario
            EyD = ((-q / 4*pi*eps0) * ry) ./ r.^3;
            EzD = ((-q / 4*pi*eps0) * rz) ./ r.^3;
            
            ED = sqrt(ExD.^2 + EyD .^2 + EzD.^2)
            
            %% Actualización de la tabla
           
            app.UITable.Data = [app.UITable.Data; size(app.UITable.Data, 1) + 1, PosX, PosY, ED, 32, "Si"];
            app.sl_psize.Enable = 'off';
            app.sl_psize_n.Enable = 'off';
            app.sl_distance.Enable = 'off';
            
            
            if size(app.UITable.Data, 1) == 5
                app.GenerarglbuloButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global contadorG
            
            contadorG = 0;
            
            update_plot(app);
            %app.UITable.ColumnName
        end

        % Value changing function: sl_distance
        function sl_distanceValueChanging(app, event)
            update_plot(app)
        end

        % Value changing function: sl_psize
        function sl_psizeValueChanging(app, event)
            update_plot(app)
        end

        % Value changing function: sl_psize_n
        function sl_psize_nValueChanging(app, event)
            update_plot(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {929, 929};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GenerarglbuloButton
        function GenerarglbuloButtonPushed(app, event)
            
            globulos(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DistanciaentreplacasSliderLabel = uilabel(app.LeftPanel);
            app.DistanciaentreplacasSliderLabel.Text = 'Distancia entre placas';
            app.sl_distance = uislider(app.LeftPanel);
            app.sl_distance.Limits = [0.01 0.5];
            app.sl_distance.ValueChangingFcn = createCallbackFcn(app, @sl_distanceValueChanging, true);
            app.sl_distance.Value = 0.1;
            app.TamaoplacapositivaLabel = uilabel(app.LeftPanel);
            app.TamaoplacapositivaLabel.Text = 'Tamaño placa positiva';
            app.sl_psize = uislider(app.LeftPanel);
            app.sl_psize.Limits = [0.1 0.5];
            app.sl_psize.ValueChangingFcn = createCallbackFcn(app, @sl_psizeValueChanging, true);
            app.sl_psize.Value = 0.1;
            app.DeteccindeMalariaLabel = uilabel(app.LeftPanel);
            app.DeteccindeMalariaLabel.Text = {'Detección de '; 'Malaria'};
            app.TamaoplacanegativaLabel = uilabel(app.LeftPanel);
            app.TamaoplacanegativaLabel.Text = {'Tamaño placa'; 'negativa'};
            app.sl_psize_n = uislider(app.LeftPanel);
            app.sl_psize_n.Limits = [0.1 0.5];
            app.sl_psize_n.ValueChangingFcn = createCallbackFcn(app, @sl_psize_nValueChanging, true);
            app.sl_psize_n.Value = 0.1;
            app.GenerarglbuloButton = uibutton(app.LeftPanel, 'push');
            app.GenerarglbuloButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarglbuloButtonPushed, true);
            app.GenerarglbuloButton.Text = 'Generar glóbulo';
            app.RightPanel = uipanel(app.GridLayout);
            app.axes = uiaxes(app.RightPanel);
            title(app.axes, 'Campo eléctrico')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Glóbulo'; 'X'; 'Y'; 'Campo'; 'Fuerza'; 'Infectado'};
            app.UITable.RowName = {'Globulo 1'; 'Globulo 2'; 'Globulo 3'; 'Globulo 4'; 'Globulo 5'};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end